classdef gliomaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        BrowseButton  matlab.ui.control.Button
        UITable       matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        % Displays all .h5 files found in the directory object in the app
        % table, with checkboxes next to them
        % Will throw an error if no .h5 files are found
        function displayFiles(app, directory)
            % Grab file list by creating a mask of dir entries that are files
            % and applying that mask to the directory contents
            % Then it transposes the array to be a column (using ')
            files = {directory(~[directory.isdir]).name}';
            % Remove all files that are not .h5 files
            % https://stackoverflow.com/a/15604522
            files = files(~cellfun('isempty', regexpi(files, '\.h5$')));
            if isempty(files)
                errordlg("Error: No .h5 files found in directory");
            end
            % natsort function credit - Stephen Cobeldick
            % https://au.mathworks.com/matlabcentral/fileexchange/34464-customizable-natural-order-sort
            files = natsort(files);
            
            % Formatting for the table to display the files
            checkboxes = num2cell(false(size(files)));
            app.UITable.ColumnWidth = {20, 'auto'};
            app.UITable.Data = [checkboxes files];
            app.UITable.ColumnEditable = [true false];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            directory = uigetdir('', 'Select a Directory');
            if directory ~= 0
                displayFiles(app, dir(directory));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Load an Image from File'};
            app.BrowseButton.Text = 'Browse...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gliomaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end